classdef CoffeePodApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        EnvironmentDropDown       matlab.ui.control.DropDown
        EnvironmentDropDownLabel  matlab.ui.control.Label
        AnomalyMapCheckBox        matlab.ui.control.CheckBox
        FPSCheckBox               matlab.ui.control.CheckBox
        TrackingCheckBox          matlab.ui.control.CheckBox
        DetectedPodsListBox       matlab.ui.control.ListBox
        DetectedPodsListBoxLabel  matlab.ui.control.Label
        AnomalyDetectionCheckBox  matlab.ui.control.CheckBox
        ObjectDetectionCheckBox   matlab.ui.control.CheckBox
        UIAxes_Pod                matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        Next                      matlab.ui.control.Button
        Prev                      matlab.ui.control.Button
        LoadVideoButton           matlab.ui.control.Button
        SkipNext                  matlab.ui.control.Button
        SkipPrev                  matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % Variables
        VideoRead = [];
        VideoStep = [];
        CurrentIdx = [];
        CurrentFrame = [];
        CurrentOverlay = [];
        ImageHandle = [];
        CurrentBoxes = [];
        CurrentLabels = [];
        CurrentDetectionsNum = [];
        CROPS = [];
        CurrentTracks = [];
        CurrentTracksIDs = [];
        CurrentAnomalyScores = [];
        CurrentAnomalyClasses = [];
        CurrentAnomalyHistory = [];
        CurrentPodImg = [];
        CurrentPodSel = [];
        CurrentFPS = 0;


        % Flags
        runAppFlag = false;
        runVideoFlag = false;
        runObjectDetectionFlag = false;
        runTrackingFlag = false;
        runAnomalyDetectionFlag = false;
        runAnomalyMapFlag = false;
        runFPSFlag = false;
        resetTrackerFlag = false;

        % AI Models functions
        ObjectDetectFunction = [];
        AnomalyDetectFunction = [];
        AnomalyMapFunction = [];
        ObjectTrackingFunction = [];

        % AI Models variables
        yoloX = "yoloxDetector.mat";
        anomalyDet = "fcddDetector.mat";

        % Cosmetics
        ClassDefinitions = categorical(["Decaf","Ristretto","Caramel","Forte","Organic"]);
        AnomalyColor = "red";
        NormalColor  = "green";
    end
    
    methods (Access = private)
        
        function runApplication(app) 
            while isvalid(app) && app.runAppFlag
                if ~hasFrame(app.VideoRead)
                    % LOOPING VIDEO
                    app.resetTracker; 
                    app.VideoRead.CurrentTime = 0;

                    % NO LOOPING VIDEO
                    % app.StopButton.Enable = "off";
                    % app.SkipPrev.Enable = "on";
                    % app.Prev.Enable = "on";
                    % app.runAppFlag = false; 
                    % break
                end   
                app.processFrame                
            end
        end
        
        function getFrame(app)
            app.CurrentFrame = readFrame(app.VideoRead);
        end


        function processFrame(app)
            app.getFrame;
            app.CurrentOverlay = app.CurrentFrame;

            if app.runObjectDetectionFlag
                if app.runTrackingFlag
                    if mod(app.CurrentIdx, 10) <= 1 %With tracking run object detection 2/10 frames
                        app.runObjectDetection;
                    else
                        app.CurrentBoxes = double.empty(0,4);
                        app.CurrentLabels = categorical("",["Decaf","Ristretto","Caramel","Forte","Organic"]);
                    end
                    app.runObjectTracking;
                else
                    app.runObjectDetection;
                end    
                
                app.generateCrops;

                if app.runAnomalyDetectionFlag
                    app.runAnomalyDetection;
                    
                    idxAnomaly = find(app.CurrentAnomalyClasses);
                    idxNormal = find(~app.CurrentAnomalyClasses);
                    stringScores = string(app.CurrentAnomalyScores);

                    app.CurrentOverlay = insertObjectAnnotation(app.CurrentOverlay, "rectangle", app.CurrentBoxes(idxAnomaly,:), ...
                        string(app.CurrentLabels(idxAnomaly)') + " : " + stringScores(idxAnomaly), AnnotationColor=app.AnomalyColor);
                    app.CurrentOverlay = insertObjectAnnotation(app.CurrentOverlay, "rectangle", app.CurrentBoxes(idxNormal,:), ...
                        string(app.CurrentLabels(idxNormal)') + " : " + stringScores(idxNormal), AnnotationColor=app.NormalColor);
                else
                    app.CurrentOverlay = insertObjectAnnotation(app.CurrentOverlay, "rectangle", app.CurrentBoxes, app.CurrentLabels');
                end
            end

            newt = toc;
            app.CurrentFPS = 0.9*app.CurrentFPS + 0.1*(1/newt);
            tic
            if app.runFPSFlag
                app.CurrentOverlay = insertText(app.CurrentOverlay, [1 1], char("FPS: "+ int2str(app.CurrentFPS)), ...
                    BoxColor=[0,0,0],BoxOpacity=1, FontSize = 20, FontColor="y");
            end

            app.ImageHandle.CData = app.CurrentOverlay;
            app.CurrentIdx = app.CurrentIdx+1;
            drawnow
        end
        
        function runObjectDetection(app)
            [bboxes, ~, labels] = app.ObjectDetectFunction(app.yoloX, app.CurrentFrame);
                [~, sortedIdx] = sort(bboxes(:,1));
                app.CurrentLabels = labels(sortedIdx);
                app.CurrentBoxes = double(bboxes(sortedIdx, :));
        end

        function runObjectTracking(app) 
            app.CurrentTracks = app.ObjectTrackingFunction(app.CurrentBoxes, app.CurrentLabels, app.CurrentIdx, app.ClassDefinitions, app.resetTrackerFlag);
            app.resetTrackerFlag = false;
            numTracks = numel(app.CurrentTracks);
            boxes = zeros(numTracks, 4);
            labels = strings(numTracks,1);
            ids = zeros(numTracks,1);
            invalidBoxIdx = [];
            for tr = 1:numTracks
                bbox = app.CurrentTracks(tr).ObjectAttributes.BoundingBox;
                bbox(1:2) = (app.CurrentTracks(tr).State(1:2:3))'-bbox(3:4)/2;
                if  bbox(1) < 0 || bbox(3) < 100 || bbox(2) < 0 || bbox(4) < 100
                    invalidBoxIdx = [invalidBoxIdx, tr]; %#ok<AGROW>
                else
                    boxes(tr, :) = bbox;
                    labels(tr) = strcat(int2str(app.CurrentTracks(tr).TrackID), ": ", string(app.ClassDefinitions(app.CurrentTracks(tr).ObjectClassID)));
                    ids(tr) = app.CurrentTracks(tr).TrackID;
                end
            end
            
            boxes(invalidBoxIdx,:) = [];
            labels(invalidBoxIdx,:) = [];
            ids(invalidBoxIdx,:) = [];
            app.CurrentLabels = labels;
            app.CurrentBoxes = boxes;
            app.CurrentTracksIDs = ids;
        end                
                
        function generateCrops(app) %Still issue with invalid bboxes when tracking (not ofter)
            app.CurrentDetectionsNum = size(app.CurrentBoxes,1);
            app.CROPS = uint8(zeros(256,256,3,app.CurrentDetectionsNum));
            for ii = 1:app.CurrentDetectionsNum
                ROI = floor(app.CurrentBoxes(ii,:));
                crop = imresize(imcrop(app.CurrentFrame, ROI), [256 256]);
                app.CROPS(:,:,:, ii) = crop;
            end
            app.DetectedPodsListBox.Items = app.CurrentLabels';
            app.DetectedPodsListBox.ItemsData = 1:app.CurrentDetectionsNum;
        end
        
        function runAnomalyDetection(app)
            app.CurrentAnomalyScores = zeros(1,app.CurrentDetectionsNum);
            app.CurrentAnomalyClasses = false(1,app.CurrentDetectionsNum);
            
            if app.runTrackingFlag
                TracksHistIDs = [app.CurrentAnomalyHistory.ID];
                [isMem, idxMem] = ismember(app.CurrentTracksIDs, TracksHistIDs); % Check which current tracks exist
                
                deletedTracksIdx = find(~ismember(1:length(TracksHistIDs), idxMem))+1;
                app.CurrentAnomalyHistory(deletedTracksIdx) = []; % Remove history of deleted tracks
                
                newTracksIds = app.CurrentTracksIDs(find(~isMem));
                for ii = 1:length(newTracksIds) % Add history of new tracks
                    app.CurrentAnomalyHistory(end+1).ID = newTracksIds(ii);
                    app.CurrentAnomalyHistory(end).isOld = false;
                end

                ProcessTrackIdx = find(~[app.CurrentAnomalyHistory.isOld]); % Process score and class of new tracks
                ProcessCrops = app.CROPS(:,:,:,ProcessTrackIdx);   
                ProcessNum = length(ProcessTrackIdx);

                ProcessScores = zeros(1,ProcessNum);
                ProcessClasses = zeros(1,ProcessNum);
                if ProcessNum > 0
                    [ProcessScores, ProcessClasses] = app.AnomalyDetectFunction(app.anomalyDet,ProcessCrops);
                end

                for ii = 1:ProcessNum % Update history tracker and assign to current values
                    app.CurrentAnomalyHistory(ProcessTrackIdx(ii)+1).Scores(end+1) = ProcessScores(ii);
                    app.CurrentAnomalyHistory(ProcessTrackIdx(ii)+1).Classes(end+1) = ProcessClasses(ii);
                    app.CurrentAnomalyHistory(ProcessTrackIdx(ii)+1).meanScore = mean(app.CurrentAnomalyHistory(ProcessTrackIdx(ii)+1).Scores);
                    app.CurrentAnomalyHistory(ProcessTrackIdx(ii)+1).modeClass = mode(app.CurrentAnomalyHistory(ProcessTrackIdx(ii)+1).Classes);
                    app.CurrentAnomalyHistory(ProcessTrackIdx(ii)+1).isOld = length(app.CurrentAnomalyHistory(ProcessTrackIdx(ii)+1).Scores) >= 10;
                end
                
                app.CurrentAnomalyScores = [app.CurrentAnomalyHistory.meanScore];
                app.CurrentAnomalyClasses = [app.CurrentAnomalyHistory.modeClass];

            else
                if app.CurrentDetectionsNum > 0
                    [app.CurrentAnomalyScores, app.CurrentAnomalyClasses] = app.AnomalyDetectFunction(app.anomalyDet,app.CROPS);
                end
            end
        end

        function clickPod(app, ~, event)
            selPosition = event.IntersectionPoint;
            selInd = [];
            for ii = 1:size(app.CurrentBoxes,1)
                curbox = app.CurrentBoxes(ii,:);
                isIn = all(curbox(1:2) < selPosition(1:2)) && all(curbox(1:2)+ curbox(3:4) > selPosition(1:2));
                if isIn 
                    selInd = ii;
                end
            end
            app.selectPod(selInd);
        end

        function selectPod(app, value)
            if isempty(value)
                app.CurrentPodSel = [];
                app.CurrentPodImg = [];
            else 
                app.CurrentPodSel = value;
                app.CurrentPodImg = app.CROPS(:,:,:,app.CurrentPodSel);
                app.DetectedPodsListBox.Value = {};
            end
            app.displayPod;
        end

        function displayPod(app)
            if app.runAnomalyMapFlag && ~isempty(app.CurrentPodImg)
                map = app.AnomalyMapFunction(app.anomalyDet, app.CurrentPodImg);
                cropDisplay = anomalyMapOverlay(app.CurrentPodImg, map, Blend="equal");
            else
                cropDisplay = app.CurrentPodImg;
            end
            imshow(cropDisplay, Parent=app.UIAxes_Pod, Border="tight", InitialMagnification="fit");
        end

        function resetTracker(app)
            app.CurrentIdx = 0;
            app.resetTrackerFlag = true;
            app.resetAnomalyHistory;
        end

        function resetAnomalyHistory(app)
            app.CurrentAnomalyHistory = struct("ID",[], "isOld", [], "meanScore", [], "Scores", [], "modeClass", [], "Classes", []);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath("..\CoffeePodApp\"))  
            app.EnvironmentDropDownValueChanged;
        end

        % Value changed function: EnvironmentDropDown
        function EnvironmentDropDownValueChanged(app, event)
            value = app.EnvironmentDropDown.Value;            
            app.resetTracker;       

            if value == "GPU"
                app.ObjectDetectFunction = @yoloDetect;
                app.AnomalyDetectFunction = @fcddPredict;
                app.AnomalyMapFunction = @fcddAnomalyMap;
                app.ObjectTrackingFunction = @gnnTrack;
            elseif value == "CPU"
                app.ObjectDetectFunction = @yoloDetect_cpu;
                app.AnomalyDetectFunction = @fcddPredict_cpu;
                app.AnomalyMapFunction = @fcddAnomalyMap_cpu;
                app.ObjectTrackingFunction = @gnnTrack;
            end
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
            videoName = "ConveyorBelt.avi";

            app.VideoRead = VideoReader(videoName);
            app.VideoStep = 1/app.VideoRead.FrameRate;

            app.CurrentFrame = readFrame(app.VideoRead);
            app.ImageHandle = imshow(app.CurrentFrame, Parent=app.UIAxes, Border="tight", InitialMagnification="fit");
            app.ImageHandle.ButtonDownFcn = @app.clickPod;
            
            app.runAppFlag = false;
            app.EnvironmentDropDownValueChanged;
            app.StartButton.Enable = "on";
            app.SkipNext.Enable = "on";
            app.SkipPrev.Enable = "off";
            app.Next.Enable = "on";
            app.Prev.Enable = "off";
            app.StopButton.Enable = "off";
            app.ObjectDetectionCheckBox.Enable = "on";
            app.FPSCheckBox.Enable = "on";
            app.resetTracker;
            app.processFrame;
        end

        % Value changed function: ObjectDetectionCheckBox
        function ObjectDetectionCheckBoxValueChanged(app, event)
            value = app.ObjectDetectionCheckBox.Value;
            app.runObjectDetectionFlag = value;
            if value
                app.TrackingCheckBox.Enable = "on"; 
                app.AnomalyDetectionCheckBox.Enable = "on";
                app.AnomalyMapCheckBox.Enable = "on";
                app.AnomalyMapCheckBoxValueChanged;
                app.DetectedPodsListBox.Enable = "on";
                app.DetectedPodsListBoxLabel.Enable = "on";
            else
                app.TrackingCheckBox.Enable = "off";
                app.AnomalyDetectionCheckBox.Enable = "off";
                app.AnomalyMapCheckBox.Enable = "off";
                app.DetectedPodsListBox.Enable = "off";
                app.DetectedPodsListBoxLabel.Enable = "off";
                app.resetTracker;
                app.DetectedPodsListBox.Value = {};
                app.DetectedPodsListBox.Items = "";
                app.DetectedPodsListBox.ItemsData = [];
            end
        end

        % Value changed function: TrackingCheckBox
        function TrackingCheckBoxValueChanged(app, event)
            value = app.TrackingCheckBox.Value;
            app.runTrackingFlag = value;
            if ~value
                app.resetTracker;
            end
        end

        % Value changed function: AnomalyDetectionCheckBox
        function AnomalyDetectionCheckBoxValueChanged(app, event)
            value = app.AnomalyDetectionCheckBox.Value;
            app.runAnomalyDetectionFlag = value;
            if ~value
                app.CurrentAnomalyScores = [];
                app.CurrentAnomalyClasses = [];
            end
        end

        % Value changed function: DetectedPodsListBox
        function DetectedPodsListBoxValueChanged(app, event)
            value = app.DetectedPodsListBox.Value;  
            app.selectPod(value);
        end

        % Value changed function: AnomalyMapCheckBox
        function AnomalyMapCheckBoxValueChanged(app, event)
            value = app.AnomalyMapCheckBox.Value;
            app.runAnomalyMapFlag = value;
            app.displayPod;
        end

        % Value changed function: FPSCheckBox
        function FPSCheckBoxValueChanged(app, event)
            value = app.FPSCheckBox.Value;
            app.runFPSFlag = value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.runAppFlag = true;
            app.StopButton.Enable = "on";
            app.StartButton.Enable = "off";
            app.SkipNext.Enable = "off";
            app.SkipPrev.Enable = "off";
            app.Next.Enable = "off";
            app.Prev.Enable = "off";
            app.runApplication;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.runAppFlag = false;
            app.StopButton.Enable = "off";
            app.StartButton.Enable = "on";
            app.SkipNext.Enable = "on";
            app.SkipPrev.Enable = "on";
            app.Next.Enable = "on";
            app.Prev.Enable = "on";
        end

        % Button pushed function: Next
        function NextButtonPushed(app, event)
            app.processFrame;
            app.SkipPrev.Enable = "on";
            app.Prev.Enable = "on";
            if ~hasFrame(app.VideoRead)
                app.SkipNext.Enable = "off";
                app.Next.Enable = "off";
                app.StartButton.Enable = "off";
            end
        end

        % Button pushed function: SkipNext
        function SkipNextPushed(app, event)
            app.VideoRead.CurrentTime = min(app.VideoRead.Duration-app.VideoStep, app.VideoRead.CurrentTime + 119*app.VideoStep);
            app.resetTracker;
            app.processFrame;
            app.SkipPrev.Enable = "on";
            app.Prev.Enable = "on";
            if ~hasFrame(app.VideoRead)
              app.SkipNext.Enable = "off";
              app.Next.Enable = "off";
              app.StartButton.Enable = "off";
            end
        end

        % Button pushed function: Prev
        function PrevButtonPushed(app, event)
            app.VideoRead.CurrentTime = max(0,app.VideoRead.CurrentTime - 2*app.VideoStep);
            app.resetTracker;
            app.processFrame;
            app.SkipNext.Enable = "on";
            app.Next.Enable = "on";
            app.StartButton.Enable = "on";
            if app.VideoRead.CurrentTime <= (app.VideoStep)
                app.SkipPrev.Enable = "off";
                app.Prev.Enable = "off";
            end
        end

        % Button pushed function: SkipPrev
        function SkipPrevPushed(app, event)
            app.VideoRead.CurrentTime = max(0, app.VideoRead.CurrentTime - 121*app.VideoStep);
            app.resetTracker;
            app.processFrame;
            app.SkipNext.Enable = "on";
            app.Next.Enable = "on";
            app.StartButton.Enable = "on";
            if app.VideoRead.CurrentTime <= (app.VideoStep)
              app.SkipPrev.Enable = "off";
              app.Prev.Enable = "off";
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {274, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.UIAxes_Pod = uiaxes(app.GridLayout3);
            app.UIAxes_Pod.XTick = [];
            app.UIAxes_Pod.YTick = [];
            app.UIAxes_Pod.ZTick = [];
            app.ObjectDetectionCheckBox = uicheckbox(app.GridLayout3);
            app.ObjectDetectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ObjectDetectionCheckBoxValueChanged, true);
            app.ObjectDetectionCheckBox.Enable = 'off';
            app.ObjectDetectionCheckBox.Text = 'Object Detection';
            app.AnomalyDetectionCheckBox = uicheckbox(app.GridLayout3);
            app.AnomalyDetectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnomalyDetectionCheckBoxValueChanged, true);
            app.AnomalyDetectionCheckBox.Enable = 'off';
            app.AnomalyDetectionCheckBox.Text = 'Anomaly Detection';
            app.DetectedPodsListBoxLabel = uilabel(app.GridLayout3);
            app.DetectedPodsListBoxLabel.Enable = 'off';
            app.DetectedPodsListBoxLabel.Text = 'Detected Pods';
            app.DetectedPodsListBox = uilistbox(app.GridLayout3);
            app.DetectedPodsListBox.Items = {};
            app.DetectedPodsListBox.ValueChangedFcn = createCallbackFcn(app, @DetectedPodsListBoxValueChanged, true);
            app.DetectedPodsListBox.Enable = 'off';
            app.DetectedPodsListBox.Value = {};
            app.TrackingCheckBox = uicheckbox(app.GridLayout3);
            app.TrackingCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackingCheckBoxValueChanged, true);
            app.TrackingCheckBox.Enable = 'off';
            app.TrackingCheckBox.Text = 'Tracking';
            app.FPSCheckBox = uicheckbox(app.GridLayout3);
            app.FPSCheckBox.ValueChangedFcn = createCallbackFcn(app, @FPSCheckBoxValueChanged, true);
            app.FPSCheckBox.Enable = 'off';
            app.FPSCheckBox.Text = 'FPS';
            app.AnomalyMapCheckBox = uicheckbox(app.GridLayout3);
            app.AnomalyMapCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnomalyMapCheckBoxValueChanged, true);
            app.AnomalyMapCheckBox.Enable = 'off';
            app.AnomalyMapCheckBox.Text = 'Anomaly Map';
            app.EnvironmentDropDownLabel = uilabel(app.GridLayout3);
            app.EnvironmentDropDownLabel.Text = 'Environment';
            app.EnvironmentDropDown = uidropdown(app.GridLayout3);
            app.EnvironmentDropDown.Items = {'GPU', 'CPU'};
            app.EnvironmentDropDown.ValueChangedFcn = createCallbackFcn(app, @EnvironmentDropDownValueChanged, true);
            app.EnvironmentDropDown.Value = 'GPU';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Anomaly Detection of Coffee Pods')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.StartButton = uibutton(app.GridLayout2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop ';
            app.SkipPrev = uibutton(app.GridLayout2, 'push');
            app.SkipPrev.ButtonPushedFcn = createCallbackFcn(app, @SkipPrevPushed, true);
            app.SkipPrev.Enable = 'off';
            app.SkipPrev.Text = '<<';
            app.SkipNext = uibutton(app.GridLayout2, 'push');
            app.SkipNext.ButtonPushedFcn = createCallbackFcn(app, @SkipNextPushed, true);
            app.SkipNext.Enable = 'off';
            app.SkipNext.Text = '>>';
            app.LoadVideoButton = uibutton(app.GridLayout2, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.Prev = uibutton(app.GridLayout2, 'push');
            app.Prev.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.Prev.Enable = 'off';
            app.Prev.Text = '<';
            app.Next = uibutton(app.GridLayout2, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.Next.Enable = 'off';
            app.Next.Text = '>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoffeePodApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end